classdef fmriNF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fMRIUIFigure                matlab.ui.Figure
        DPABILabel_2                matlab.ui.control.Label
        DPABILabel                  matlab.ui.control.Label
        SPMLabel                    matlab.ui.control.Label
        Button_2                    matlab.ui.control.Button
        timepointEditField          matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        sliceorderEditField         matlab.ui.control.EditField
        sliceorderEditField_2Label  matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        TRsEditField                matlab.ui.control.NumericEditField
        TRsEditFieldLabel           matlab.ui.control.Label
        slicenumberEditField        matlab.ui.control.NumericEditField
        EditField_3Label_2          matlab.ui.control.Label
        tmpEditField                matlab.ui.control.EditField
        Label_4                     matlab.ui.control.Label
        EditFieldLabel              matlab.ui.control.Label
        aalEditField                matlab.ui.control.EditField
        fcbachEditField             matlab.ui.control.EditField
        fcLabel                     matlab.ui.control.Label
        spmEditField                matlab.ui.control.EditField
        spmEditFieldLabel           matlab.ui.control.Label
        txtEditField                matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        sjwzEditField               matlab.ui.control.EditField
        EditField_3Label            matlab.ui.control.Label
        startLJEditField            matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Future  % 后台运行脚本的句柄
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 获取用户输入
            app.Button.Enable="off";
            app.Button.Text='正在运行';
            n= app.nEditField.Value;
            timepoint= app.timepointEditField.Value;
            start= app.startLJEditField.Value;
            sj= app.sjwzEditField.Value;
            Txt= app.txtEditField.Value;
            work_lj= app.spmEditField.Value;
            tp= app.tmpEditField.Value;
            sn= app.slicenumberEditField.Value;
            tr= app.TRsEditField.Value;
            order= eval(app.sliceorderEditField.Value);
            ref= app.EditField.Value;
            fc= app.fcbachEditField.Value;
            aal= app.aalEditField.Value;
            pause(0);
            % 脚本执行
            parpool(n);
            app.Future =parfeval(@main_NF,0,n,tp,sn,tr,order,ref,timepoint,start,sj,Txt,work_lj,fc,aal);
            app.Button_2.Enable="on";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cancel(app.Future);
            delete(gcp('nocreate'));
            app.Button_2.Enable="off";
            app.Button.Enable="on";
            app.Button.Text='运行';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fMRIUIFigure = uifigure('Visible', 'off');
            app.fMRIUIFigure.Name = '实时fMRI分析';
            app.Label = uilabel(app.fMRIUIFigure);
            app.Label.Text = '并行数量';
            app.nEditField = uieditfield(app.fMRIUIFigure, 'numeric');
            app.nEditField.Value = 1;
            app.Label_2 = uilabel(app.fMRIUIFigure);
            app.Label_2.Text = '起始路径';
            app.startLJEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.startLJEditField.Value = 'D:\fmriNF';
            app.EditField_3Label = uilabel(app.fMRIUIFigure);
            app.EditField_3Label.Text = '数据位置';
            app.sjwzEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.sjwzEditField.Placeholder = '132';
            app.sjwzEditField.Value = 'D:\fmriNF\test\dcm';
            app.Button = uibutton(app.fMRIUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tooltip = {''};
            app.Button.Text = '运行';
            app.Label_3 = uilabel(app.fMRIUIFigure);
            app.Label_3.Text = '指标存放位置';
            app.txtEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.txtEditField.Value = 'D:\fmriNF\111.txt';
            app.spmEditFieldLabel = uilabel(app.fMRIUIFigure);
            app.spmEditFieldLabel.Text = 'spm工作路径';
            app.spmEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.spmEditField.Value = 'D:\fmriNF\test';
            app.fcLabel = uilabel(app.fMRIUIFigure);
            app.fcLabel.Text = 'fc计算bach';
            app.fcbachEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.fcbachEditField.Value = 'D:\fmriNF\fc_bach.mat';
            app.aalEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.aalEditField.Value = 'D:\Program Files\MATLAB\matlab tool\DPABI_V8.2_240510\Templates\aal.nii';
            app.EditFieldLabel = uilabel(app.fMRIUIFigure);
            app.EditFieldLabel.Text = '脑图谱模板';
            app.Label_4 = uilabel(app.fMRIUIFigure);
            app.Label_4.Text = '标准化模板';
            app.tmpEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.tmpEditField.Value = 'D:\Program Files\MATLAB\matlab tool\spm12\tpm\TPM.nii';
            app.EditField_3Label_2 = uilabel(app.fMRIUIFigure);
            app.EditField_3Label_2.Text = 'slice number';
            app.slicenumberEditField = uieditfield(app.fMRIUIFigure, 'numeric');
            app.slicenumberEditField.Value = 62;
            app.TRsEditFieldLabel = uilabel(app.fMRIUIFigure);
            app.TRsEditFieldLabel.Text = 'TR(s)';
            app.TRsEditField = uieditfield(app.fMRIUIFigure, 'numeric');
            app.TRsEditField.Value = 2;
            app.Label_6 = uilabel(app.fMRIUIFigure);
            app.Label_6.Text = '参考层： 第                                 层';
            app.EditField = uieditfield(app.fMRIUIFigure, 'numeric');
            app.EditField.Value = 62;
            app.sliceorderEditField_2Label = uilabel(app.fMRIUIFigure);
            app.sliceorderEditField_2Label.Text = 'slice order';
            app.sliceorderEditField = uieditfield(app.fMRIUIFigure, 'text');
            app.sliceorderEditField.Value = '[2:2:62 1:2:62]';
            app.Label_5 = uilabel(app.fMRIUIFigure);
            app.Label_5.Text = '处理数量：             个TR';
            app.timepointEditField = uieditfield(app.fMRIUIFigure, 'numeric');
            app.timepointEditField.Value = 5;
            app.Button_2 = uibutton(app.fMRIUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Tooltip = {''};
            app.Button_2.Text = '终止';
            app.SPMLabel = uilabel(app.fMRIUIFigure);
            app.SPMLabel.Text = 'SPM参数';
            app.DPABILabel = uilabel(app.fMRIUIFigure);
            app.DPABILabel.Text = 'DPABI参数';
            app.DPABILabel_2 = uilabel(app.fMRIUIFigure);
            app.DPABILabel_2.Text = 'DPABI中导出';
            % Show the figure after all components are created
            app.fMRIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmriNF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.fMRIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fMRIUIFigure)
        end
    end
end